classdef projectV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        ImageProcessingToolLabel    matlab.ui.control.Label
        SelectImageButton           matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        ImagePreprocessingDropDownLabel  matlab.ui.control.Label
        ImagePreprocessingDropDown  matlab.ui.control.DropDown
        RotateImageDropDownLabel    matlab.ui.control.Label
        RotateImageDropDown         matlab.ui.control.DropDown
        AdvancedDropDownLabel       matlab.ui.control.Label
        AdvancedDropDown            matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Value changed function: ImagePreprocessingDropDown
        function ImagePreprocessingDropDownValueChanged(app, event)
            value = app.ImagePreprocessingDropDown.Value;
            A = imread(app.Image2.ImageSource);
            if strcmp(value,'Change Brightness')
                B = A+50;
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Change Contrast')
                low = 0.4;
                high = 0.9;
                B = imadjust(A,[low high],[]);
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Increase Saturation')
                HSV = rgb2hsv(A);     
                HSV(:, :, 2) = HSV(:, :, 2) * 1.9;
                HSV(HSV > 1) = 1;  % Limit values
                B = hsv2rgb(HSV);
                montage({A,B})
                title("Original Image (Left) vs. Saturated Image (Right)");
            elseif strcmp(value,'Increase Hue')
                HSV = rgb2hsv(A);
                HSV(:, :, 1) = HSV(:, :, 1) * 1.8;
                HSV(HSV > 1) = 1;  % Limit values
                B = hsv2rgb(HSV);
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Convert to B&W')    
                B = rgb2gray(A);
                montage({A,B})
                title("Original Image (Left) vs. B&W Image (Right)");
            end            
        end

        % Value changed function: RotateImageDropDown
        function RotateImageDropDownValueChanged(app, event)
            value = app.RotateImageDropDown.Value;
            A = imread(app.Image2.ImageSource);
            if strcmp(value,'Rotate Clockwise')
                B = imrotate(A,-90);
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Rotate AntiClockwise')
                B = imrotate(A,90);
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Mirror Image')  
                B = flipdim(A ,2); 
                montage({A,B})
                title("Original Image (Left) vs. Mirrored Image (Right)");
            end
        end

        % Value changed function: AdvancedDropDown
        function AdvancedDropDownValueChanged(app, event)
            value = app.AdvancedDropDown.Value;
            A = imread(app.Image2.ImageSource);
            if strcmp(value,'Crop')
                [B, ~] = imcrop(A);
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Blur')
                for i = 1:3
                    B(:,:,i) = uint8(conv2(A(:,:,i),ones(20)/20^2,'same'));
                end
                montage({A,B})
                title("Original Image (Left) vs. Modified Image (Right)");
            elseif strcmp(value,'Detect Circles')
                [centers,radii] = imfindcircles(A,[20 25],'ObjectPolarity','dark', ...
                 'Sensitivity',0.95, 'EdgeThreshold',0.1);
                [centersBright,radiiBright] = imfindcircles(A,[20 25], ...
            'ObjectPolarity','bright','Sensitivity',0.95, 'EdgeThreshold',0.1);
                imshow(A);            
                h = viscircles(centers,radii);
                hBright = viscircles(centersBright, radiiBright,'Color','b');
            elseif strcmp(value,'Remove Haze')
                B = imreducehaze(A);
                montage({A,B})
                title("Hazy Image (Left) vs. Reduced Haze Image (Right)");
            end
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           
           app.Image2.ImageSource = fullname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Software.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.ImageProcessingToolLabel = uilabel(app.UIFigure);
            app.ImageProcessingToolLabel.Text = 'Image Processing Tool';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ImagePreprocessingDropDownLabel = uilabel(app.UIFigure);
            app.ImagePreprocessingDropDownLabel.Text = 'Image Pre-processing';
            app.ImagePreprocessingDropDown = uidropdown(app.UIFigure);
            app.ImagePreprocessingDropDown.Items = {'Increase Saturation', 'Increase Hue', 'Change Contrast', 'Change Brightness', 'Convert to B&W'};
            app.ImagePreprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagePreprocessingDropDownValueChanged, true);
            app.ImagePreprocessingDropDown.Value = 'Increase Saturation';
            app.RotateImageDropDownLabel = uilabel(app.UIFigure);
            app.RotateImageDropDownLabel.Text = 'Rotate Image';
            app.RotateImageDropDown = uidropdown(app.UIFigure);
            app.RotateImageDropDown.Items = {'Rotate Clockwise', 'Rotate AntiClockwise', 'Mirror Image'};
            app.RotateImageDropDown.ValueChangedFcn = createCallbackFcn(app, @RotateImageDropDownValueChanged, true);
            app.RotateImageDropDown.Value = 'Rotate Clockwise';
            app.AdvancedDropDownLabel = uilabel(app.UIFigure);
            app.AdvancedDropDownLabel.Text = 'Advanced';
            app.AdvancedDropDown = uidropdown(app.UIFigure);
            app.AdvancedDropDown.Items = {'Crop', 'Blur', 'Detect Circles', 'Remove Haze'};
            app.AdvancedDropDown.ValueChangedFcn = createCallbackFcn(app, @AdvancedDropDownValueChanged, true);
            app.AdvancedDropDown.Value = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectV4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end